classdef in_ear_test_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        ExperimentConfigurationLabel    matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ApplyButton                     matlab.ui.control.Button
        DeviceIRNormalizationButton     matlab.ui.control.Button
        BufferSIzeEditField             matlab.ui.control.NumericEditField
        BufferSIzeEditFieldLabel        matlab.ui.control.Label
        scalarformonoor12vectorforstereoLabel  matlab.ui.control.Label
        RecorderChannelMappingEditField  matlab.ui.control.EditField
        RecorderChannelMappingEditFieldLabel  matlab.ui.control.Label
        usedforasinglebutstereochanneloutputLabel  matlab.ui.control.Label
        PlayerChannelMappingEditField   matlab.ui.control.EditField
        PlayerChannelMappingEditFieldLabel  matlab.ui.control.Label
        BitDepthDropDown                matlab.ui.control.DropDown
        BitDepthDropDownLabel           matlab.ui.control.Label
        SampleRateDropDown              matlab.ui.control.DropDown
        SampleRateDropDownLabel         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        DescriptionTab                  matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        SoundsgoodButton                matlab.ui.control.Button
        ANTSLabInearExperimentLabel     matlab.ui.control.Label
        ThisexperimentcollectshumaninearacousticrefelectiondataLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AcknowledgementTab              matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        ack_checkbox2                   matlab.ui.control.CheckBox
        NLabel                          matlab.ui.control.Label
        ack_checkbox1                   matlab.ui.control.CheckBox
        AcknowledgementLabel            matlab.ui.control.Label
        ThisexperimentcollectshumaninearacousticrefelectiondataLabel_2  matlab.ui.control.Label
        ParticipantInfoTab              matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        GenderLabel                     matlab.ui.control.Label
        EarConditionDropDown            matlab.ui.control.DropDown
        PleaseprovideyourinformationLabel  matlab.ui.control.Label
        EarConditionDropDownLabel       matlab.ui.control.Label
        ParticipantTypeDropDown         matlab.ui.control.DropDown
        ParticipantTypeDropDownLabel    matlab.ui.control.Label
        GenderDropDown                  matlab.ui.control.DropDown
        Spinner_infoq2                  matlab.ui.control.Spinner
        SpinnerLabel_infoq2             matlab.ui.control.Label
        Last3digitsofphoneforidentifierpurposeEditField  matlab.ui.control.EditField
        Last3digitsofphoneforidentifierpurposeEditFieldLabel  matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        SpinnerLabel                    matlab.ui.control.Label
        AgeapproxSpinner                matlab.ui.control.Spinner
        AgeLabel                        matlab.ui.control.Label
        InitialsEditField               matlab.ui.control.EditField
        InitialsEditFieldLabel          matlab.ui.control.Label
        NextButton_4                    matlab.ui.control.Button
        SetupTab                        matlab.ui.container.Tab
        DuringrecordingyoucandoanythingandmakeyourselfcomfortableLabel  matlab.ui.control.Label
        DonotspeakduringeachrecordinguntilgreenlightisonLabel  matlab.ui.control.Label
        NextButton_2                    matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        VolumedBFSSpinner               matlab.ui.control.Spinner
        VolumedBFSSpinnerLabel          matlab.ui.control.Label
        ClicktotestButton               matlab.ui.control.Button
        AdjustthevolumeandgetreadyLabel  matlab.ui.control.Label
        AdjusttheearbudstoacomfortablepositionLabel  matlab.ui.control.Label
        AllowthefoamtorecoverandfirmlyfitsyourearLabel  matlab.ui.control.Label
        FirmlyinserttheearbudsuntilyoufilltheboneLabel  matlab.ui.control.Label
        YoumaywanttocleanyourearsandeartipsLabel  matlab.ui.control.Label
        InstructionLabel                matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        LeftLabel                       matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        DataCollectingTab               matlab.ui.container.Tab
        ClickeachbuttonandlistenuntilallgreenlightsonLabel  matlab.ui.control.Label
        OneclickLabel                   matlab.ui.control.Label
        Lamp_10                         matlab.ui.control.Lamp
        NormalUse30smusic3Button        matlab.ui.control.Button
        DropDown_3                      matlab.ui.control.DropDown
        NextButton_3                    matlab.ui.control.Button
        Lamp_9                          matlab.ui.control.Lamp
        NormalUse30smusic2Button        matlab.ui.control.Button
        DropDown_2                      matlab.ui.control.DropDown
        Lamp_8                          matlab.ui.control.Lamp
        NormalUse30smusic1Button        matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        Lamp_5                          matlab.ui.control.Lamp
        EarCannalScanninginaudible20Button  matlab.ui.control.Button
        Lamp_7                          matlab.ui.control.Lamp
        NormalUse1minspeech2Button      matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        EarCannalScanningchirp20Button  matlab.ui.control.Button
        Lamp_6                          matlab.ui.control.Lamp
        NormalUse1minspeech1Button      matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        EarCannalScanningMLS20Button    matlab.ui.control.Button
        DataCollectionLabel             matlab.ui.control.Label
        VisualizationTab                matlab.ui.container.Tab
        NextButton_5                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        VisualizetheEarCanalReflectanceLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        FinishTab                       matlab.ui.container.Tab
        NewUserButton                   matlab.ui.control.Button
        AssistantsNoteEditField         matlab.ui.control.EditField
        AssistantsNoteEditFieldLabel    matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        YouremailoptionalEditField      matlab.ui.control.EditField
        YouremailoptionalEditFieldLabel  matlab.ui.control.Label
        Alsowearecurrentlyapplying510giftcardsforeveryparticipantsLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ThankyouLabel                   matlab.ui.control.Label
    end


    properties (Access = public)
        % data to save
        user_ir_MLS = {};% user's impulse response using MLS;
        user_ir_chirp={}; %user's impulse response using chirp;
        user_ir_inaudible={}; % user's impulse response using inaudible chirp;
        user_rec_male_speech={};
        user_rec_female_speech={};
        user_music_1 = {};
        user_music_2 = {};
        user_music_3 = {};
        device_ir={}; % devices ir used for normalization;

        % test
        outputLevel=-40; % experiment volume 20*log(scale)
        aPR = audioPlayerRecorder; % variable: audio player and recorder

    end

    methods (Access = private)

        function AckChecked(app)
            if app.ack_checkbox1.Value && app.ack_checkbox2.Value
                app.NextButton.Enable = 'on';
            end
        end

        function InfoFilled(app)
            if ~isempty(app.InitialsEditField.Value) && ~isempty(app.Last3digitsofphoneforidentifierpurposeEditField)
                app.NextButton_4.Enable = 'on';
            end
        end

        function PlotEarReflectance(app)
            if ~isempty(app.user_ir_MLS)
                data_ir = app.user_ir_MLS{1};
                data_ir_left = data_ir(:,1);
                data_ir_right = data_ir(:,2);

                fs = app.aPR.SampleRate;
                Nfft = 2^ceil(log2(size(data_ir,1)));

                [fr_left,freq] = freqz(data_ir_left,1,Nfft,fs);
                [fr_right,~] = freqz(data_ir_right,1,Nfft,fs);

                plot(app.UIAxes,log(freq,fr_left))
                hold(app.UIAxes, 'on');
                grid(app.UIAxes, 'on');
                xlabel(app.UIAxes, 'frequency(Hz)')
                ylabel(app.UIAxes, 'Reflectance (dB)')

                plot(app.UIAxes_2,log(freq,fr_right))
                hold(app.UIAxes_2, 'on');
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, 'frequency(Hz)')
                ylabel(app.UIAxes_2, 'Reflectance (dB)')
            end


        end

        function ClearEarReflectance(app)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % function and class
            % app.aPR = audioPlayerRecorder;
            devices = getAudioDevices(app.aPR);

            % app module initialization
            app.DeviceDropDown.Items = devices;

            % music folder
            folderPath = 'audio/music_48k';
            wavFiles = dir(fullfile(folderPath, '*.wav'));
            fileNames = cell(length(wavFiles), 1);
            for k = 1:length(wavFiles)
                fileNames{k} = wavFiles(k).name;
            end


            app.DropDown_3.Items = fileNames;
            app.DropDown_2.Items = fileNames;
            app.DropDown.Items = fileNames;

            app.DropDown_3.Value = fileNames{3};
            app.DropDown_2.Value = fileNames{2};
            app.DropDown.Value = fileNames{1};
        end

        % Button pushed function: SoundsgoodButton
        function SoundsgoodButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AcknowledgementTab;
        end

        % Value changed function: ack_checkbox1, ack_checkbox2
        function ack_checkbox1ValueChanged(app, event)
            AckChecked(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParticipantInfoTab;
        end

        % Value changed function: InitialsEditField, 
        % ...and 1 other component
        function InitialsEditFieldValueChanged(app, event)
            InfoFilled(app);
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.SetupTab;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            audiodevreset;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.aPR.Device = app.DeviceDropDown.Value;
            app.aPR.SampleRate = str2double(app.SampleRateDropDown.Value);
            app.aPR.BitDepth = app.BitDepthDropDown.Value;
            app.aPR.BufferSize = app.BufferSIzeEditField.Value;
            eval("app.aPR.PlayerChannelMapping="+app.PlayerChannelMappingEditField.Value);
            eval("app.aPR.RecorderChannelMapping="+app.RecorderChannelMappingEditField.Value);
        end

        % Button pushed function: ClicktotestButton
        function ClicktotestButtonPushed(app, event)
            filename = "audio/notification/leftright_48k.wav";
            [~,noError] = EarCanalPlay(app.aPR,filename,app.VolumedBFSSpinner.Value);
            if noError == 1
                app.Lamp_2.Color = 'green';
            else
                app.Lamp_2.Color = 'red';
            end
        end

        % Button pushed function: DeviceIRNormalizationButton
        function DeviceIRNormalizationButtonPushed(app, event)
            [ir_open,noError] = EarCanalScan(app.aPR,"MLS",-40);
            if noError == 1
                app.Lamp.Color = 'green';
                app.device_ir = ir_open;
            else
                app.Lamp.Color = 'red';
                app.device_ir = [];
            end
        end

        % Value changed function: VolumedBFSSpinner
        function VolumedBFSSpinnerValueChanged(app, event)
            app.outputLevel = app.VolumedBFSSpinner.Value - 25;

        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.outputLevel = app.VolumedBFSSpinner.Value - 25;
            app.TabGroup.SelectedTab = app.DataCollectingTab;
        end

        % Button pushed function: EarCannalScanningMLS20Button
        function EarCannalScanningMLS20ButtonPushed(app, event)
            user_MLS = {};
            for num = 1:20
                [ir_MLS,noError] = EarCanalScan(app.aPR,"MLS",app.outputLevel);
                if noError
                    app.Lamp_3.Color = 'green';
                    user_MLS{end+1} = ir_MLS;
                else
                    app.Lamp_3.Color = 'red';
                    user_MLS = {};
                    break;
                end
            end
            app.user_ir_MLS = user_MLS;
        end

        % Button pushed function: EarCannalScanningchirp20Button
        function EarCannalScanningchirp20ButtonPushed(app, event)
            user_chirp = {};
            for num = 1:20
                [ir_chirp,noError] = EarCanalScan(app.aPR,"chirp",app.outputLevel);
                if noError
                    app.Lamp_4.Color = 'green';
                    user_chirp{end+1} = ir_chirp;
                else
                    app.Lamp_4.Color = 'red';
                    user_chirp = {};
                    break;
                end
            end
            app.user_ir_chirp = user_chirp;
        end

        % Button pushed function: EarCannalScanninginaudible20Button
        function EarCannalScanninginaudible20ButtonPushed(app, event)
            user_inaudible = {};
            for num = 1:20
                [ir_inaudible,noError] = EarCanalScan(app.aPR,"inaudible",app.outputLevel);
                if noError
                    app.Lamp_5.Color = 'green';
                    user_inaudible{end+1} = ir_inaudible;
                else
                    app.Lamp_5.Color = 'red';
                    user_inaudible = {};
                    break;
                end
            end
            app.user_ir_inaudible = user_inaudible;
        end

        % Button pushed function: NormalUse1minspeech1Button
        function NormalUse1minspeech1ButtonPushed(app, event)
            filename = "audio/speech_clip_48k/man_speech_48k.wav";
            [speech,noError] = EarCanalPlay(app.aPR,filename,app.outputLevel+20);
            if noError
                app.Lamp_6.Color = 'green';
                app.user_rec_male_speech{1} = speech;
            else
                app.Lamp_6.Color = 'red';
            end
        end

        % Button pushed function: NormalUse1minspeech2Button
        function NormalUse1minspeech2ButtonPushed(app, event)
            filename = "audio/speech_clip_48k/women_speech_48k.wav";
            [speech,noError] = EarCanalPlay(app.aPR,filename,app.outputLevel+20);
            if noError
                app.Lamp_7.Color = 'green';
                app.user_rec_female_speech{1} = speech;
            else
                app.Lamp_7.Color = 'red';
            end
        end

        % Button pushed function: NormalUse30smusic1Button
        function NormalUse30smusic1ButtonPushed(app, event)
            folderPath = 'audio/music_48k';
            filename = fullfile(folderPath,app.DropDown.Value);
            [music,noError] = EarCanalPlay(app.aPR,filename,app.outputLevel+20);
            if noError
                app.Lamp_8.Color = 'green';
                app.user_music_1{1} = filename;
                app.user_music_1{2} = music;
            else
                app.Lamp_8.Color = 'red';
            end
        end

        % Button pushed function: NormalUse30smusic2Button
        function NormalUse30smusic2ButtonPushed(app, event)
            folderPath = 'audio/music_48k';
            filename = fullfile(folderPath,app.DropDown_2.Value);
            [music,noError] = EarCanalPlay(app.aPR,filename,app.outputLevel+20);
            if noError
                app.Lamp_9.Color = 'green';
                app.user_music_2{1} = filename;
                app.user_music_2{2} = music;
            else
                app.Lamp_9.Color = 'red';
            end
        end

        % Button pushed function: NormalUse30smusic3Button
        function NormalUse30smusic3ButtonPushed(app, event)
            folderPath = 'audio/music_48k';
            filename = fullfile(folderPath,app.DropDown_3.Value);
            [music,noError] = EarCanalPlay(app.aPR,filename,app.outputLevel+20);
            if noError
                app.Lamp_10.Color = 'green';
                app.user_music_3{1} = filename;
                app.user_music_3{2} = music;
            else
                app.Lamp_10.Color = 'red';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            appData = struct();
            %
            appData.Date = app.DateDatePicker.Value;
            %
            appData.Config.SampleRate = app.SampleRateDropDown.Value;
            appData.Config.BitDepth = app.BitDepthDropDown.Value;
            appData.Config.BufferSize = app.BufferSIzeEditField.Value;
            appData.Config.DeviceIR = app.device_ir;
            %
            appData.Info.Name = app.InitialsEditField.Value;
            appData.Info.Age = app.AgeapproxSpinner.Value;
            appData.Info.Phone = app.Last3digitsofphoneforidentifierpurposeEditField.Value;
            appData.Info.Type = app.ParticipantTypeDropDown.Value;
            appData.Info.Gender = app.GenderDropDown.Value;
            appData.Info.Condition = app.EarConditionDropDown.Value;
            appData.Info.Freq = app.Spinner.Value;
            appData.Info.Duration = app.Spinner_infoq2.Value;
            appData.Info.Email = app.YouremailoptionalEditField.Value;
            %
            appData.Data.ir_MLS = app.user_ir_MLS;
            appData.Data.ir_chirp = app.user_ir_chirp;
            appData.Data.ir_inaudible = app.user_ir_inaudible;
            appData.Data.speech1 = app.user_rec_male_speech;
            appData.Data.speech2 = app.user_rec_female_speech;
            appData.Data.music1 = app.user_music_1;
            appData.Data.music2 = app.user_music_2;
            appData.Data.music3 = app.user_music_3;
            %
            appData.Note = app.AssistantsNoteEditField.Value;
            %
            matfile = strcat(string(datetime("now")),appData.Info.Name,...
                appData.Info.Phone,'.mat');
            filename = fullfile("saved",matfile);
            save(filename,"appData")


        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.VisualizationTab;
        end

        % Button pushed function: NewUserButton
        function NewUserButtonPushed(app, event)
            % clear all filled blank
            app.ack_checkbox1.Value = 0;
            app.ack_checkbox2.Value = 0;

            app.InitialsEditField.Value = '';
            app.AgeapproxSpinner.Value = 25;
            app.Spinner.Value = 3;
            app.Last3digitsofphoneforidentifierpurposeEditField.Value = '';
            app.Spinner_infoq2.Value = 5;
            app.GenderDropDown.Value = ' ';
            app.ParticipantTypeDropDown.Value = ' ';
            app.EarConditionDropDown.Value = ' ';

            gray_color = [0.651 0.651 0.651];
            app.Lamp_2.Color = gray_color;
            app.Lamp_3.Color = gray_color;
            app.Lamp_4.Color = gray_color;
            app.Lamp_5.Color = gray_color;
            app.Lamp_6.Color = gray_color;
            app.Lamp_7.Color = gray_color;
            app.Lamp_8.Color = gray_color;
            app.Lamp_9.Color = gray_color;
            app.Lamp_10.Color = gray_color;

            % clear recordings
            app.user_ir_MLS = {};% user's impulse response using MLS;
            app.user_ir_chirp={}; %user's impulse response using chirp;
            app.user_ir_inaudible={}; % user's impulse response using inaudible chirp;
            app.user_rec_male_speech={};
            app.user_rec_female_speech={};
            app.user_music_1 = {};
            app.user_music_2 = {};
            app.user_music_3 = {};

            % go back to the first page
            app.TabGroup.SelectedTab = app.DescriptionTab;

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            ClearEarReflectance(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            PlotEarReflectance(app);
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.FinishTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.DeviceDropDownLabel = uilabel(app.ConfigTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ConfigTab);
            app.Button = uibutton(app.ConfigTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'img', 'refresh.png');
            app.Button.Text = '';
            app.SampleRateDropDownLabel = uilabel(app.ConfigTab);
            app.SampleRateDropDownLabel.Text = 'SampleRate';
            app.SampleRateDropDown = uidropdown(app.ConfigTab);
            app.SampleRateDropDown.Items = {'48000', '44100', '32000', '16000', '8000'};
            app.SampleRateDropDown.Value = '48000';
            app.BitDepthDropDownLabel = uilabel(app.ConfigTab);
            app.BitDepthDropDownLabel.Text = 'BitDepth';
            app.BitDepthDropDown = uidropdown(app.ConfigTab);
            app.BitDepthDropDown.Items = {'16-bit integer', '8-bit integer'};
            app.BitDepthDropDown.Value = '16-bit integer';
            app.PlayerChannelMappingEditFieldLabel = uilabel(app.ConfigTab);
            app.PlayerChannelMappingEditFieldLabel.Text = 'PlayerChannelMapping';
            app.PlayerChannelMappingEditField = uieditfield(app.ConfigTab, 'text');
            app.PlayerChannelMappingEditField.Value = '[ ]';
            app.usedforasinglebutstereochanneloutputLabel = uilabel(app.ConfigTab);
            app.usedforasinglebutstereochanneloutputLabel.Text = '[ ] used for a single but stereo channel output.';
            app.RecorderChannelMappingEditFieldLabel = uilabel(app.ConfigTab);
            app.RecorderChannelMappingEditFieldLabel.Text = 'RecorderChannelMapping';
            app.RecorderChannelMappingEditField = uieditfield(app.ConfigTab, 'text');
            app.RecorderChannelMappingEditField.Value = '[1 2 ]';
            app.scalarformonoor12vectorforstereoLabel = uilabel(app.ConfigTab);
            app.scalarformonoor12vectorforstereoLabel.Text = '1,2 (scalar) for mono or [1,2] (vector) for stereo';
            app.BufferSIzeEditFieldLabel = uilabel(app.ConfigTab);
            app.BufferSIzeEditFieldLabel.Text = 'BufferSIze';
            app.BufferSIzeEditField = uieditfield(app.ConfigTab, 'numeric');
            app.BufferSIzeEditField.Value = 1024;
            app.DeviceIRNormalizationButton = uibutton(app.ConfigTab, 'push');
            app.DeviceIRNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @DeviceIRNormalizationButtonPushed, true);
            app.DeviceIRNormalizationButton.Text = 'Device IR Normalization';
            app.ApplyButton = uibutton(app.ConfigTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Lamp = uilamp(app.ConfigTab);
            app.ExperimentConfigurationLabel = uilabel(app.ConfigTab);
            app.ExperimentConfigurationLabel.Text = 'Experiment Configuration';
            app.DescriptionTab = uitab(app.TabGroup);
            app.DescriptionTab.Title = 'Description';
            app.GridLayout4 = uigridlayout(app.DescriptionTab);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'uh_red.png');
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel = uilabel(app.GridLayout4);
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel.Text = 'This experiment collects human in-ear acoustic refelection data, or in-ear acoustic. The data is collected by our hand-crafted in-ear device: feedfoward probe microphone mounted on an earplug. This research aims to explore the user-level uniqueness of in-ear acoustic and the potential use for user authentication. Thank you for  your contribution!';
            app.ANTSLabInearExperimentLabel = uilabel(app.GridLayout4);
            app.ANTSLabInearExperimentLabel.Text = 'ANTS Lab In-ear Experiment';
            app.SoundsgoodButton = uibutton(app.GridLayout4, 'push');
            app.SoundsgoodButton.ButtonPushedFcn = createCallbackFcn(app, @SoundsgoodButtonPushed, true);
            app.SoundsgoodButton.Text = 'Sounds good!';
            app.Image2 = uiimage(app.GridLayout4);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img', 'hearing.png');
            app.Image2_2 = uiimage(app.GridLayout4);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'img', 'secure.png');
            app.DateDatePickerLabel = uilabel(app.GridLayout4);
            app.DateDatePickerLabel.Text = 'Date ';
            app.DateDatePicker = uidatepicker(app.GridLayout4);
            app.DateDatePicker.DisplayFormat = 'dd-MMM-uuuu';
            app.AcknowledgementTab = uitab(app.TabGroup);
            app.AcknowledgementTab.Title = 'Acknowledgement';
            app.GridLayout3 = uigridlayout(app.AcknowledgementTab);
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel_2 = uilabel(app.GridLayout3);
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel_2.Text = 'This research collects data from human subjects. You will be given a consent form indicating that this research is approved by our university''s IRB. By signing the form, you agree to have your data collected, stored, and processed for this study. After completing ALL experiments, you will be eligible to receive the reward :)';
            app.AcknowledgementLabel = uilabel(app.GridLayout3);
            app.AcknowledgementLabel.Text = 'Acknowledgement';
            app.ack_checkbox1 = uicheckbox(app.GridLayout3);
            app.ack_checkbox1.ValueChangedFcn = createCallbackFcn(app, @ack_checkbox1ValueChanged, true);
            app.ack_checkbox1.Text = 'I agree to have my in-ear data collected in this study.';
            app.NLabel = uilabel(app.GridLayout3);
            app.NLabel.Text = 'Our experiment employs commercial devices for data collection and is designed to avoid any physical injury.';
            app.ack_checkbox2 = uicheckbox(app.GridLayout3);
            app.ack_checkbox2.ValueChangedFcn = createCallbackFcn(app, @ack_checkbox1ValueChanged, true);
            app.ack_checkbox2.Text = 'I hereby confirm that my ears do not have any medical conditions and are free from contagious diseases.';
            app.NextButton = uibutton(app.GridLayout3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ParticipantInfoTab = uitab(app.TabGroup);
            app.ParticipantInfoTab.Title = 'Participant Info';
            app.GridLayout5 = uigridlayout(app.ParticipantInfoTab);
            app.NextButton_4 = uibutton(app.GridLayout5, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Text = 'Next';
            app.InitialsEditFieldLabel = uilabel(app.GridLayout5);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsEditField = uieditfield(app.GridLayout5, 'text');
            app.InitialsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsEditFieldValueChanged, true);
            app.AgeLabel = uilabel(app.GridLayout5);
            app.AgeLabel.Text = 'Age ';
            app.AgeapproxSpinner = uispinner(app.GridLayout5);
            app.AgeapproxSpinner.Limits = [0 99];
            app.AgeapproxSpinner.Value = 25;
            app.SpinnerLabel = uilabel(app.GridLayout5);
            app.SpinnerLabel.Text = 'How frequently do you use head/earphones on a scale of 0 to 5, where 0 indicates ''never'' and 5 indicates ''very frequently''?';
            app.Spinner = uispinner(app.GridLayout5);
            app.Spinner.Limits = [0 5];
            app.Spinner.Value = 3;
            app.Last3digitsofphoneforidentifierpurposeEditFieldLabel = uilabel(app.GridLayout5);
            app.Last3digitsofphoneforidentifierpurposeEditFieldLabel.Text = 'Last 4 digits of phone # (identifier purpose)';
            app.Last3digitsofphoneforidentifierpurposeEditField = uieditfield(app.GridLayout5, 'text');
            app.Last3digitsofphoneforidentifierpurposeEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsEditFieldValueChanged, true);
            app.SpinnerLabel_infoq2 = uilabel(app.GridLayout5);
            app.SpinnerLabel_infoq2.Text = 'How long do you typically use them in one session? Please estimate in minutes.';
            app.Spinner_infoq2 = uispinner(app.GridLayout5);
            app.Spinner_infoq2.Limits = [0 120];
            app.Spinner_infoq2.Value = 5;
            app.GenderDropDown = uidropdown(app.GridLayout5);
            app.GenderDropDown.Items = {'Male', 'Female', 'Non-binary', 'Prefer not to say', ' '};
            app.GenderDropDown.Value = ' ';
            app.ParticipantTypeDropDownLabel = uilabel(app.GridLayout5);
            app.ParticipantTypeDropDownLabel.Text = 'Participant Type';
            app.ParticipantTypeDropDown = uidropdown(app.GridLayout5);
            app.ParticipantTypeDropDown.Items = {'New', 'Return', ' '};
            app.ParticipantTypeDropDown.Value = ' ';
            app.EarConditionDropDownLabel = uilabel(app.GridLayout5);
            app.EarConditionDropDownLabel.Text = 'Ear Condition';
            app.PleaseprovideyourinformationLabel = uilabel(app.GridLayout5);
            app.PleaseprovideyourinformationLabel.Text = 'Please provide your information.';
            app.EarConditionDropDown = uidropdown(app.GridLayout5);
            app.EarConditionDropDown.Items = {'Normal', 'Special Attention', 'Prefer not to say', ' '};
            app.EarConditionDropDown.Value = ' ';
            app.GenderLabel = uilabel(app.GridLayout5);
            app.GenderLabel.Text = 'Gender';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.Image3 = uiimage(app.SetupTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'img', 'right_ear.png');
            app.Image4 = uiimage(app.SetupTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'img', 'left_ear.png');
            app.LeftLabel = uilabel(app.SetupTab);
            app.LeftLabel.Text = 'Left';
            app.RightLabel = uilabel(app.SetupTab);
            app.RightLabel.Text = 'Right';
            app.InstructionLabel = uilabel(app.SetupTab);
            app.InstructionLabel.Text = 'Instruction';
            app.YoumaywanttocleanyourearsandeartipsLabel = uilabel(app.SetupTab);
            app.YoumaywanttocleanyourearsandeartipsLabel.Text = '1. You may want to clean your ears and eartips.';
            app.FirmlyinserttheearbudsuntilyoufilltheboneLabel = uilabel(app.SetupTab);
            app.FirmlyinserttheearbudsuntilyoufilltheboneLabel.Text = '2. Firmly insert the earbuds until you fill the bone. ';
            app.AllowthefoamtorecoverandfirmlyfitsyourearLabel = uilabel(app.SetupTab);
            app.AllowthefoamtorecoverandfirmlyfitsyourearLabel.Text = '3. Allow the foam to recover and firmly fits your ear.  ';
            app.AdjusttheearbudstoacomfortablepositionLabel = uilabel(app.SetupTab);
            app.AdjusttheearbudstoacomfortablepositionLabel.Text = '4. Adjust the earbuds to a comfortable position. ';
            app.AdjustthevolumeandgetreadyLabel = uilabel(app.SetupTab);
            app.AdjustthevolumeandgetreadyLabel.Text = '5. Adjust the volume and get ready. ';
            app.ClicktotestButton = uibutton(app.SetupTab, 'push');
            app.ClicktotestButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktotestButtonPushed, true);
            app.ClicktotestButton.Text = 'Click to test';
            app.VolumedBFSSpinnerLabel = uilabel(app.SetupTab);
            app.VolumedBFSSpinnerLabel.Text = 'Volume (dBFS)';
            app.VolumedBFSSpinner = uispinner(app.SetupTab);
            app.VolumedBFSSpinner.Step = 10;
            app.VolumedBFSSpinner.Limits = [-50 0];
            app.VolumedBFSSpinner.ValueChangedFcn = createCallbackFcn(app, @VolumedBFSSpinnerValueChanged, true);
            app.VolumedBFSSpinner.Value = -20;
            app.Lamp_2 = uilamp(app.SetupTab);
            app.NextButton_2 = uibutton(app.SetupTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.DonotspeakduringeachrecordinguntilgreenlightisonLabel = uilabel(app.SetupTab);
            app.DonotspeakduringeachrecordinguntilgreenlightisonLabel.Text = '6. Do not speak during each recording until green light is on.';
            app.DuringrecordingyoucandoanythingandmakeyourselfcomfortableLabel = uilabel(app.SetupTab);
            app.DuringrecordingyoucandoanythingandmakeyourselfcomfortableLabel.Text = '7. During recording, you can do anything and make yourself comfortable.';
            app.DataCollectingTab = uitab(app.TabGroup);
            app.DataCollectingTab.Title = 'Data Collecting';
            app.DataCollectionLabel = uilabel(app.DataCollectingTab);
            app.DataCollectionLabel.Text = 'Data Collection';
            app.EarCannalScanningMLS20Button = uibutton(app.DataCollectingTab, 'push');
            app.EarCannalScanningMLS20Button.ButtonPushedFcn = createCallbackFcn(app, @EarCannalScanningMLS20ButtonPushed, true);
            app.EarCannalScanningMLS20Button.Text = 'Ear Cannal Scanning - MLS (20)';
            app.Lamp_3 = uilamp(app.DataCollectingTab);
            app.NormalUse1minspeech1Button = uibutton(app.DataCollectingTab, 'push');
            app.NormalUse1minspeech1Button.ButtonPushedFcn = createCallbackFcn(app, @NormalUse1minspeech1ButtonPushed, true);
            app.NormalUse1minspeech1Button.Text = 'Normal Use - 1min speech 1';
            app.Lamp_6 = uilamp(app.DataCollectingTab);
            app.EarCannalScanningchirp20Button = uibutton(app.DataCollectingTab, 'push');
            app.EarCannalScanningchirp20Button.ButtonPushedFcn = createCallbackFcn(app, @EarCannalScanningchirp20ButtonPushed, true);
            app.EarCannalScanningchirp20Button.Text = 'Ear Cannal Scanning - chirp(20)';
            app.Lamp_4 = uilamp(app.DataCollectingTab);
            app.NormalUse1minspeech2Button = uibutton(app.DataCollectingTab, 'push');
            app.NormalUse1minspeech2Button.ButtonPushedFcn = createCallbackFcn(app, @NormalUse1minspeech2ButtonPushed, true);
            app.NormalUse1minspeech2Button.Text = 'Normal Use - 1min speech 2';
            app.Lamp_7 = uilamp(app.DataCollectingTab);
            app.EarCannalScanninginaudible20Button = uibutton(app.DataCollectingTab, 'push');
            app.EarCannalScanninginaudible20Button.ButtonPushedFcn = createCallbackFcn(app, @EarCannalScanninginaudible20ButtonPushed, true);
            app.EarCannalScanninginaudible20Button.Text = 'Ear Cannal Scanning - inaudible(20)';
            app.Lamp_5 = uilamp(app.DataCollectingTab);
            app.DropDown = uidropdown(app.DataCollectingTab);
            app.NormalUse30smusic1Button = uibutton(app.DataCollectingTab, 'push');
            app.NormalUse30smusic1Button.ButtonPushedFcn = createCallbackFcn(app, @NormalUse30smusic1ButtonPushed, true);
            app.NormalUse30smusic1Button.Text = 'Normal Use - 30s music 1';
            app.Lamp_8 = uilamp(app.DataCollectingTab);
            app.DropDown_2 = uidropdown(app.DataCollectingTab);
            app.NormalUse30smusic2Button = uibutton(app.DataCollectingTab, 'push');
            app.NormalUse30smusic2Button.ButtonPushedFcn = createCallbackFcn(app, @NormalUse30smusic2ButtonPushed, true);
            app.NormalUse30smusic2Button.Text = 'Normal Use - 30s music 2';
            app.Lamp_9 = uilamp(app.DataCollectingTab);
            app.NextButton_3 = uibutton(app.DataCollectingTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.DropDown_3 = uidropdown(app.DataCollectingTab);
            app.NormalUse30smusic3Button = uibutton(app.DataCollectingTab, 'push');
            app.NormalUse30smusic3Button.ButtonPushedFcn = createCallbackFcn(app, @NormalUse30smusic3ButtonPushed, true);
            app.NormalUse30smusic3Button.Text = 'Normal Use - 30s music 3';
            app.Lamp_10 = uilamp(app.DataCollectingTab);
            app.OneclickLabel = uilabel(app.DataCollectingTab);
            app.OneclickLabel.Text = 'One click! ';
            app.ClickeachbuttonandlistenuntilallgreenlightsonLabel = uilabel(app.DataCollectingTab);
            app.ClickeachbuttonandlistenuntilallgreenlightsonLabel.Text = 'Click each button and listen until all green lights on.';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationTab);
            title(app.UIAxes, 'Left')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.VisualizationTab);
            title(app.UIAxes_2, 'Right')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.VisualizetheEarCanalReflectanceLabel = uilabel(app.VisualizationTab);
            app.VisualizetheEarCanalReflectanceLabel.Text = 'Visualize the Ear Canal Reflectance';
            app.PlotButton = uibutton(app.VisualizationTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.VisualizationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.NextButton_5 = uibutton(app.VisualizationTab, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next';
            app.FinishTab = uitab(app.TabGroup);
            app.FinishTab.Title = 'Finish';
            app.ThankyouLabel = uilabel(app.FinishTab);
            app.ThankyouLabel.Text = 'Thank you！';
            app.Label = uilabel(app.FinishTab);
            app.Label.Text = 'We are glad to share you our finding of your in-ear acoustic biometric! Are you interested in how unique your ear canal among all participants? If so, we can send you an email! ';
            app.Alsowearecurrentlyapplying510giftcardsforeveryparticipantsLabel = uilabel(app.FinishTab);
            app.Alsowearecurrentlyapplying510giftcardsforeveryparticipantsLabel.Text = 'Also, we are currently applying ($5~10) gift cards for every participants. Once it is approved, we will comtact you on how to claim. We also wanted to invite your for return study in two weeks. You can earn another gift card for your next visit.';
            app.YouremailoptionalEditFieldLabel = uilabel(app.FinishTab);
            app.YouremailoptionalEditFieldLabel.Text = 'Your email (optional)';
            app.YouremailoptionalEditField = uieditfield(app.FinishTab, 'text');
            app.SaveButton = uibutton(app.FinishTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AssistantsNoteEditFieldLabel = uilabel(app.FinishTab);
            app.AssistantsNoteEditFieldLabel.Text = 'Assistant ''s Note';
            app.AssistantsNoteEditField = uieditfield(app.FinishTab, 'text');
            app.NewUserButton = uibutton(app.FinishTab, 'push');
            app.NewUserButton.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUserButton.Icon = fullfile(pathToMLAPP, 'img', 'refresh.png');
            app.NewUserButton.Text = 'New User';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = in_ear_test_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end