classdef in_ear_test_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        ThisexperimentcollectshumaninearacousticrefelectiondataLabel_3  matlab.ui.control.Label
        EchoMatchInEarIdentificationLabel  matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        HardwareConfigurationTab        matlab.ui.container.Tab
        EnrollRepeatNumberEditField     matlab.ui.control.NumericEditField
        EnrollRepeatNumberEditFieldLabel  matlab.ui.control.Label
        SelectScanSignalDropDown        matlab.ui.control.DropDown
        SelectScanSignalDropDownLabel   matlab.ui.control.Label
        ScanningConfigurationLabel      matlab.ui.control.Label
        ApplyandTestButton              matlab.ui.control.Button
        VolumedBFSSpinner               matlab.ui.control.Spinner
        VolumedBFSSpinnerLabel          matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Lamp_1                          matlab.ui.control.Lamp
        LeftLabel                       matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        HardwareConfigurationLabel      matlab.ui.control.Label
        BufferSIzeEditField             matlab.ui.control.NumericEditField
        BufferSIzeEditFieldLabel        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RecorderChannelMappingEditField  matlab.ui.control.EditField
        RecorderChannelMappingEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PlayerChannelMappingEditField   matlab.ui.control.EditField
        PlayerChannelMappingEditFieldLabel  matlab.ui.control.Label
        BitDepthDropDown                matlab.ui.control.DropDown
        BitDepthDropDownLabel           matlab.ui.control.Label
        SampleRateDropDown              matlab.ui.control.DropDown
        SampleRateDropDownLabel         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        EnrollTab                       matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        outputenrollLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ClearButton                     matlab.ui.control.Button
        EnrollButton                    matlab.ui.control.Button
        AddanotheruserButton            matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        ScanningwaveformLabel           matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        ScanButton                      matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        LoginTab                        matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        AuthStatusSuccessFailureLabel   matlab.ui.control.Label
        CannalScanAuthenticationButton  matlab.ui.control.Button
        YourNameEditField               matlab.ui.control.EditField
        YourNameEditFieldLabel          matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % data to save
        user_ir = {};% user's impulse response using MLS;

        % test
        outputLevel=-40; % experiment volume 20*log(scale)
        aPR = audioPlayerRecorder; % variable: audio player and recorder

    end

    methods (Access = private)

        function AckChecked(app)
            if app.ack_checkbox1.Value && app.ack_checkbox2.Value
                app.NextButton.Enable = 'on';
            end
        end

        function InfoFilled(app)
            if ~isempty(app.InitialsEditField.Value) && ~isempty(app.Last3digitsofphoneforidentifierpurposeEditField)
                app.NextButton_4.Enable = 'on';
            end
        end

        function PlotEarReflectance(app)
            % 检查 user_ir_MLS 是否为空
            if isempty(app.user_ir)
                disp('Error: No data to plot.');
                return;
            end

            data_ir = app.user_ir{1};
            % 确保数据非空，并且检查其维度
            if size(data_ir, 2) == 1
            % 单声道处理
                data_ir_left = data_ir(:, 1);
                fs = app.aPR.SampleRate;
                Nfft = 2^ceil(log2(size(data_ir, 1)));
                [fr_left, freq] = freqz(data_ir_left, 1, Nfft, fs);

                % 绘制左声道（单声道）
                plot(app.UIAxes, log(freq), fr_left);
                hold(app.UIAxes, 'on');
                grid(app.UIAxes, 'on');
                xlabel(app.UIAxes, 'Frequency (Hz)');
                ylabel(app.UIAxes, 'Reflectance (dB)');

            elseif size(data_ir, 2) >= 2
            % 双声道处理
                data_ir_left = data_ir(:, 1);
                data_ir_right = data_ir(:, 2);
                fs = app.aPR.SampleRate;
                Nfft = 2^ceil(log2(size(data_ir, 1)));

                % 计算左声道频率响应
                [fr_left, freq] = freqz(data_ir_left, 1, Nfft, fs);
                % 计算右声道频率响应
                [fr_right, ~] = freqz(data_ir_right, 1, Nfft, fs);

                % 绘制左声道
                plot(app.UIAxes, log(freq), fr_left);
                hold(app.UIAxes, 'on');
                grid(app.UIAxes, 'on');
                xlabel(app.UIAxes, 'Frequency (Hz)');
                ylabel(app.UIAxes, 'Reflectance (dB)');

                % 绘制右声道
                plot(app.UIAxes_2, log(freq), fr_right);
                hold(app.UIAxes_2, 'on');
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, 'Frequency (Hz)');
                ylabel(app.UIAxes_2, 'Reflectance (dB)');
            else
                disp('Error: Invalid data format.');
            end
        end

        function ClearEarReflectance(app)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % function and class
            app.TabGroup.Visible = 'on';
            app.aPR = audioPlayerRecorder;
            app.aPR.SampleRate = 44100;
            devices = getAudioDevices(app.aPR);
            disp(devices);
            % app module initialization
            app.DeviceDropDown.Items = devices;

            % music folder
            folderPath = 'audio/music_48k';
            wavFiles = dir(fullfile(folderPath, '*.wav'));
            fileNames = cell(length(wavFiles), 1);
            for k = 1:length(wavFiles)
                fileNames{k} = wavFiles(k).name;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            audiodevreset;
        end

        % Button pushed function: ApplyandTestButton
        function ApplyandTestButtonPushed(app, event)
            app.Lamp_1.Color = 'black';
            release(app.aPR);

            app.aPR.Device = app.DeviceDropDown.Value;
            app.aPR.SampleRate = str2double(app.SampleRateDropDown.Value);
            app.aPR.BitDepth = app.BitDepthDropDown.Value;
            app.aPR.BufferSize = app.BufferSIzeEditField.Value;
            eval("app.aPR.PlayerChannelMapping="+app.PlayerChannelMappingEditField.Value);
            eval("app.aPR.RecorderChannelMapping="+app.RecorderChannelMappingEditField.Value);

            filename = "audio/notification/leftright_48k.wav";
            [~,noError] = EarCanalPlay(app.aPR,filename,app.VolumedBFSSpinner.Value);
            if noError == 1
                app.Lamp_1.Color = 'green';
            else
                app.Lamp_1.Color = 'red';
            end
        end

        % Value changed function: VolumedBFSSpinner
        function VolumedBFSSpinnerValueChanged(app, event)
            app.outputLevel = app.VolumedBFSSpinner.Value - 25;

        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            % 获取用户输入的参数
            scanSignal = app.SelectScanSignalDropDown.Value; % 获取选择的扫描信号
            repeatNum = app.EnrollRepeatNumberEditField.Value; % 获取enroll repeat number

            user_ir = {};
            
            if scanSignal == "MLS"
                for num = 1:repeatNum
                    [ir_MLS,noError] = EarCanalScan(app.aPR,"MLS",app.outputLevel);
                    if noError
                        app.Lamp_2.Color = 'green';
                        user_ir{end+1} = ir_MLS;
                    else
                        app.Lamp_2.Color = 'red';
                        user_ir = {};
                        break;
                    end
                end
                app.user_ir = user_ir;
            elseif scanSignal == "chirp"
                for num = 1:repeatNum
                    [ir_chirp,noError] = EarCanalScan(app.aPR,"chirp",app.outputLevel);
                    if noError
                        app.Lamp_2.Color = 'green';
                        user_ir{end+1} = ir_chirp;
                    else
                        app.Lamp_2.Color = 'red';
                        user_ir = {};
                        break;
                    end
                end
                app.user_ir = user_ir;
            else
                disp('Error: select one scan signal');

            end

            % 绘制MLS
            % 检查 user_ir_MLS 是否为空
            if isempty(user_ir)
                disp('Error: No data to plot.');
                return;
            end
            data_ir = user_ir{1};
            % 确保数据非空，并且检查其维度
            if size(data_ir, 2) == 1
            % 单声道处理
                data_ir_left = data_ir(:, 1);
                fs = app.aPR.SampleRate;
                Nfft = 2^ceil(log2(size(data_ir, 1)));
                [fr_left, freq] = freqz(data_ir_left, 1, Nfft, fs);

                % 绘制左声道（单声道）
                plot(app.UIAxes_3, log(freq), fr_left);
                hold(app.UIAxes_3, 'on');
                grid(app.UIAxes_3, 'on');
                xlabel(app.UIAxes_3, 'Frequency (Hz)');
                ylabel(app.UIAxes_3, 'Reflectance (dB)');

            elseif size(data_ir, 2) >= 2
            % 双声道处理
                data_ir_left = data_ir(:, 1);
                data_ir_right = data_ir(:, 2);
                fs = app.aPR.SampleRate;
                Nfft = 2^ceil(log2(size(data_ir, 1)));

                % 计算左声道频率响应
                [fr_left, freq] = freqz(data_ir_left, 1, Nfft, fs);
                % 计算右声道频率响应
                [fr_right, ~] = freqz(data_ir_right, 1, Nfft, fs);

                % 绘制左声道
                plot(app.UIAxes_3, log(freq), fr_left);
                hold(app.UIAxes_3, 'on');
                grid(app.UIAxes_3, 'on');
                xlabel(app.UIAxes_3, 'Frequency (Hz)');
                ylabel(app.UIAxes_3, 'Reflectance (dB)');

                % 绘制右声道
                plot(app.UIAxes_4, log(freq), fr_right);
                hold(app.UIAxes_4, 'on');
                grid(app.UIAxes_4, 'on');
                xlabel(app.UIAxes_4, 'Frequency (Hz)');
                ylabel(app.UIAxes_4, 'Reflectance (dB)');
            else
                disp('Error: Invalid data format.');
            end

        end

        % Button pushed function: AddanotheruserButton
        function AddUserButtonPushed(app, event)
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);

            % 将灯的颜色重置为初始状态
            app.Lamp_1.Color = [0.902 0.902 0.902]; % 恢复为灰色
            app.Lamp_2.Color = [0.902 0.902 0.902]; % 恢复为灰色

            % 重置其他控件，比如用户名和音量等
            app.UsernameEditField.Value = '';
            app.VolumedBFSSpinner.Value = -20; % 重置音量为初始值
            app.SelectScanSignalDropDown.Value = 'MLS'; % 重置扫描信号
            app.EnrollRepeatNumberEditField.Value = 0; % 重置重复次数
            app.SignalDurationEditField.Value = 0; % 重置信号持续时间
            app.IntervalDurationEditField.Value = 0; % 重置间隔时间
            
            % 打印一条调试信息
            disp('UI reset to initial state, ready for new user.');
            app.TabGroup.SelectedTab = app.WelcomeTab;
        end

        % Button pushed function: EnrollButton
        function EnrollButtonPushed(app, event)
            userName = app.UsernameEditField.Value; % 获取用户名
            
            appData = struct();
            appData.Config.SampleRate = app.SampleRateDropDown.Value;
            appData.Config.BitDepth = app.BitDepthDropDown.Value;
            appData.Config.BufferSize = app.BufferSIzeEditField.Value;            %
            appData.Info.Name = userName;  % 保存userName到结构体中

            % 保存扫描的IR数据
            appData.Data.ir = app.user_ir;

            % 定义保存文件夹和文件名
            folderPath = 'saved';
            if ~exist(folderPath, 'dir')  % 如果文件夹不存在，则创建
                mkdir(folderPath);
            end
            matfile = strcat(string(datetime("now")), '_', userName, '.mat');
            filename = fullfile("saved",matfile);
            save(filename,"appData")

            % 保存用户的注册信息
            app.Label_4.Text = sprintf('%s is enrolled.', userName);
        
            % 更新 Label_5 的显示内容，记录所有已注册用户名
            currentText = app.Label_5.Text;
            if isempty(currentText)
                app.Label_5.Text = sprintf('Enrolled Users:\n%s', userName);
            else
                app.Label_5.Text = sprintf('%s\n%s', currentText, userName);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.Image2_2 = uiimage(app.WelcomeTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'img', 'secure.png');
            app.EchoMatchInEarIdentificationLabel = uilabel(app.WelcomeTab);
            app.EchoMatchInEarIdentificationLabel.Text = 'EchoMatch In Ear Identification';
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel_3 = uilabel(app.WelcomeTab);
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel_3.Text = 'This experiment collects human in-ear acoustic refelection data, or in-ear acoustic. The data is collected by our hand-crafted in-ear device: feedfoward probe microphone mounted on an earplug. This research aims to explore the user-level uniqueness of in-ear acoustic and the potential use for user authentication. Thank you for  your contribution!';
            app.Image5 = uiimage(app.WelcomeTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'img', 'hearing.png');
            app.HardwareConfigurationTab = uitab(app.TabGroup);
            app.HardwareConfigurationTab.Title = 'Hardware Configuration';
            app.DeviceDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.HardwareConfigurationTab);
            app.Button = uibutton(app.HardwareConfigurationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'img', 'refresh.png');
            app.Button.Text = '';
            app.SampleRateDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.SampleRateDropDownLabel.Text = 'SampleRate';
            app.SampleRateDropDown = uidropdown(app.HardwareConfigurationTab);
            app.SampleRateDropDown.Items = {'48000', '44100', '32000', '16000', '8000'};
            app.SampleRateDropDown.Value = '48000';
            app.BitDepthDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.BitDepthDropDownLabel.Text = 'BitDepth';
            app.BitDepthDropDown = uidropdown(app.HardwareConfigurationTab);
            app.BitDepthDropDown.Items = {'16-bit integer', '8-bit integer'};
            app.BitDepthDropDown.Value = '16-bit integer';
            app.PlayerChannelMappingEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.PlayerChannelMappingEditFieldLabel.Text = 'PlayerChannelMapping';
            app.PlayerChannelMappingEditField = uieditfield(app.HardwareConfigurationTab, 'text');
            app.PlayerChannelMappingEditField.Value = '[1 2]';
            app.Label_2 = uilabel(app.HardwareConfigurationTab);
            app.Label_2.Text = '';
            app.RecorderChannelMappingEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.RecorderChannelMappingEditFieldLabel.Text = 'RecorderChannelMapping';
            app.RecorderChannelMappingEditField = uieditfield(app.HardwareConfigurationTab, 'text');
            app.RecorderChannelMappingEditField.Value = '[1 2]';
            app.Label_3 = uilabel(app.HardwareConfigurationTab);
            app.Label_3.Text = '';
            app.BufferSIzeEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.BufferSIzeEditFieldLabel.Text = 'BufferSIze';
            app.BufferSIzeEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.BufferSIzeEditField.Value = 1024;
            app.HardwareConfigurationLabel = uilabel(app.HardwareConfigurationTab);
            app.HardwareConfigurationLabel.Text = 'Hardware Configuration';
            app.Image4 = uiimage(app.HardwareConfigurationTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'img', 'left_ear.png');
            app.LeftLabel = uilabel(app.HardwareConfigurationTab);
            app.LeftLabel.Text = 'Left';
            app.Lamp_1 = uilamp(app.HardwareConfigurationTab);
            app.Image3 = uiimage(app.HardwareConfigurationTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'img', 'right_ear.png');
            app.RightLabel = uilabel(app.HardwareConfigurationTab);
            app.RightLabel.Text = 'Right';
            app.VolumedBFSSpinnerLabel = uilabel(app.HardwareConfigurationTab);
            app.VolumedBFSSpinnerLabel.Text = 'Volume (dBFS)';
            app.VolumedBFSSpinner = uispinner(app.HardwareConfigurationTab);
            app.VolumedBFSSpinner.Step = 10;
            app.VolumedBFSSpinner.Limits = [-50 0];
            app.VolumedBFSSpinner.ValueChangedFcn = createCallbackFcn(app, @VolumedBFSSpinnerValueChanged, true);
            app.VolumedBFSSpinner.Value = -20;
            app.ApplyandTestButton = uibutton(app.HardwareConfigurationTab, 'push');
            app.ApplyandTestButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyandTestButtonPushed, true);
            app.ApplyandTestButton.Text = 'Apply and Test';
            app.ScanningConfigurationLabel = uilabel(app.HardwareConfigurationTab);
            app.ScanningConfigurationLabel.Text = 'Scanning Configuration';
            app.SelectScanSignalDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.SelectScanSignalDropDownLabel.Text = 'Select Scan Signal';
            app.SelectScanSignalDropDown = uidropdown(app.HardwareConfigurationTab);
            app.SelectScanSignalDropDown.Items = {'MLS', 'chirp'};
            app.SelectScanSignalDropDown.Value = 'MLS';
            app.EnrollRepeatNumberEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.EnrollRepeatNumberEditFieldLabel.Text = 'Enroll Repeat Number';
            app.EnrollRepeatNumberEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.EnrollTab = uitab(app.TabGroup);
            app.EnrollTab.Title = 'Enroll';
            app.UIAxes_3 = uiaxes(app.EnrollTab);
            title(app.UIAxes_3, 'Left')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.EnrollTab);
            title(app.UIAxes_4, 'Right')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ScanButton = uibutton(app.EnrollTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.UsernameEditFieldLabel = uilabel(app.EnrollTab);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.EnrollTab, 'text');
            app.ScanningwaveformLabel = uilabel(app.EnrollTab);
            app.ScanningwaveformLabel.Text = 'Scanning waveform';
            app.Lamp_2 = uilamp(app.EnrollTab);
            app.AddanotheruserButton = uibutton(app.EnrollTab, 'push');
            app.AddanotheruserButton.ButtonPushedFcn = createCallbackFcn(app, @AddUserButtonPushed, true);
            app.AddanotheruserButton.Text = 'Add another user';
            app.EnrollButton = uibutton(app.EnrollTab, 'push');
            app.EnrollButton.ButtonPushedFcn = createCallbackFcn(app, @EnrollButtonPushed, true);
            app.EnrollButton.Text = 'Enroll';
            app.ClearButton = uibutton(app.EnrollTab, 'push');
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.EnrollTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'uh_red.png');
            app.outputenrollLabel = uilabel(app.EnrollTab);
            app.outputenrollLabel.Text = 'output enroll';
            app.Label_4 = uilabel(app.EnrollTab);
            app.Label_4.Text = '';
            app.LoginTab = uitab(app.TabGroup);
            app.LoginTab.Title = 'Login';
            app.UIAxes_5 = uiaxes(app.LoginTab);
            title(app.UIAxes_5, 'Left')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.LoginTab);
            title(app.UIAxes_6, 'Right')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.YourNameEditFieldLabel = uilabel(app.LoginTab);
            app.YourNameEditFieldLabel.Text = 'Your Name';
            app.YourNameEditField = uieditfield(app.LoginTab, 'text');
            app.CannalScanAuthenticationButton = uibutton(app.LoginTab, 'push');
            app.CannalScanAuthenticationButton.Text = 'Cannal Scan Authentication';
            app.AuthStatusSuccessFailureLabel = uilabel(app.LoginTab);
            app.AuthStatusSuccessFailureLabel.Text = 'Auth Status: Success/Failure';
            app.Label_5 = uilabel(app.LoginTab);
            app.Label_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = in_ear_test_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end